classdef explore_factors < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExploreFactorsUIFigure     matlab.ui.Figure
        ShowexcludedROIsCheckBox   matlab.ui.control.CheckBox
        SelectEventButton          matlab.ui.control.Button
        GenerateimagesButton       matlab.ui.control.Button
        Tree                       matlab.ui.container.CheckBoxTree
        RenderingMode              matlab.ui.control.DropDown
        DropDownLabel              matlab.ui.control.Label
        LoadTrialButton            matlab.ui.control.Button
        LoaddataatTimepointButton  matlab.ui.control.Button
        BranchDropDown             matlab.ui.control.DropDown
        BranchDropDownLabel        matlab.ui.control.Label
        TreeDisplayButtonGroup     matlab.ui.container.ButtonGroup
        MetricDropDown             matlab.ui.control.DropDown
        MetricDropDownLabel        matlab.ui.control.Label
        NClustThrEditField         matlab.ui.control.NumericEditField
        NClustThrEditFieldLabel    matlab.ui.control.Label
        NFactorsSpinner            matlab.ui.control.Spinner
        NFactorsSpinnerLabel       matlab.ui.control.Label
        MethodDropDown_2           matlab.ui.control.DropDown
        MethodDropDown_2Label      matlab.ui.control.Label
        MethodDropDown             matlab.ui.control.DropDown
        MethodDropDownLabel        matlab.ui.control.Label
        ClustersButton             matlab.ui.control.ToggleButton
        FactorsButton              matlab.ui.control.ToggleButton
        BranchesButton             matlab.ui.control.ToggleButton
        Loadings_fig               matlab.ui.control.UIAxes
        Traces_fig                 matlab.ui.control.UIAxes
        Tree_fig                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        expe % Description
        highlight_trace % Description
        current_record_data % Description
        current_record_number = 0 % Description
        current_bap = [];
        event_index = [];
    end
    
    methods (Access = private)
        
        function embed_tree(app, fig_num)
            if nargin < 2 || isempty(fig_num)
                fig     = gcf;
            elseif ~ishandle(fig_num)
                fig     = figure(fig_num);
            else
                fig     = fig_num;
            end
            
            cla(app.Tree_fig)
            ax      = findobj(fig,'Type','Axes');  
            copyobj(ax.Children, app.Tree_fig); 
            rotate3d(app.Tree_fig,'on');axis(app.Tree_fig, 'off')
            adjust_tree_plot('3D view',app.Tree_fig);
            caxis(app.Tree_fig,[0,0.2]);
            close(fig);
        end
        
        function embed_map(app, fig_num)
            if nargin < 2 || isempty(fig_num)
                fig     = gcf;
            elseif ~ishandle(fig_num)
                fig     = figure(fig_num);
            else
                fig     = fig_num;
            end
            
            cla(app.Loadings_fig)
            ax      = findobj(fig,'Type','Axes');  
            copyobj(ax, app.Loadings_fig); 
            %caxis(app.Tree_fig,[0,0.2]);
            close(fig);
        end
        
        function im = load_bap(app, record, pt_in_record, window)
            
            source = app.expe.arboreal_scans{record}.data_folder;
            ap = app.expe.arboreal_scans{record}.analysis_params;
            p = analysis_params('data_folder',ap.data_folder,...
                                'data_type'      , 'concatenated',...
                                   'rendering_mode',  app.RenderingMode.Value,...
                                   'tracing_source' , 'swc'         , ...
                                   'signal_channel' , 2             ,...
                                   'registration_channel', 2,.... % to fix
                                   'registration'   , true          ,...
                                   'registration_reload','auto_offsets.mat',...
                                   'smoothing'      , [10,0]        ,...
                                   'mask_method'    , ''   ,...
                                   'mask_value'     , ''     ,...
                                   'compression'    , '2D'          ,...
                                   'rendering'      , false         ,...
                                   'z_score'        ,-5,...
                                   'ROIs', find(app.expe.dimensionality.valid_trace_idx)); 
            
            if app.ShowexcludedROIsCheckBox.Value
                p.ROIs = 0;
            end
            
            if record == app.current_record_number
                data = app.current_record_data;
            else
                data = load_ribbon_scan(p);   
                app.current_record_number = record;
                app.current_record_data = data;
            end
            
            if nargin > 3 && ~isempty(window)
                window = window(window > 0 & window <= size(data{1}, 4));
                im = max_projection(data{1}(:,:,:,window,:),'','mean');                
            else
                lim = [5,10];
                im = max_projection(data{1}(:,:,:,max(pt_in_record-lim(1), 1):min(pt_in_record+lim(2), end),:),'','mean');
            end
            im = im(:,:,1);
        end
        
        function pause(app)
            for el = app.ExploreFactorsUIFigure.Children'
                try
                    el.Enable = 'off';
                end                
            end
            pause(0.05)
        end
        
        
        function resume(app)
            for el = app.ExploreFactorsUIFigure.Children'
                try
                    el.Enable = 'on';
                end                
            end
            pause(0.05)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, expe)
            app.expe = expe;
            app.TreeDisplayButtonGroup.SelectedObject = app.TreeDisplayButtonGroup.Buttons(2);
            app.TreeDisplayButtonGroupSelectionChanged('');
            
            peak_t = vertcat(app.expe.event.peak_time{:});
            current = 1;
            for record = 1:numel(app.expe.timescale.tp)
                start = current;
                stop = current + app.expe.timescale.tp(record) - 1;
                incl = find(peak_t >= start & peak_t <= stop);
                Top_Node = app.Tree;
                Event_Node = uitreenode(Top_Node,'Text',['Record ',num2str(record)]);
                if ~isempty(incl)                    
                    for ev = incl'
                        uitreenode(Event_Node,'Text',['Event ',num2str(ev), ' t = ', num2str(peak_t(ev))]);  
                        ev_ori = find(cellfun(@(x) any(ismember(x,peak_t(ev))), app.expe.event.peak_time));
                        app.event_index(end+1,:) = [record, ev, ev_ori];
                    end
                else
                    Event_Node.HandleVisibility = 'off';
                end   
                expand(Top_Node)
                current = current + app.expe.timescale.tp(record);
            end
            
        end

        % Selection changed function: TreeDisplayButtonGroup
        function TreeDisplayButtonGroupSelectionChanged(app, event)
            app.pause()
            mode = app.TreeDisplayButtonGroup.SelectedObject;
            app.BranchDropDown.Value = 'All'; % reset selection
            if strcmpi(mode.Text, 'branches')
                app.BranchDropDownLabel.Text = 'branch #';
                app.BranchDropDown.Items = splitlines(sprintf('\n%i',1:numel(app.expe.ref.indices.br_start_idx)));
                app.BranchDropDown.Items{1} = 'All';
            elseif strcmpi(mode.Text, 'factors') 
                app.BranchDropDownLabel.Text = 'factor #';
                app.BranchDropDown.Items = splitlines(sprintf('\n%i',1:app.expe.dimensionality.n_factors));
                app.BranchDropDown.Items{1} = 'All'; 
                app.expe.plot_factor_tree('','','', app.Loadings_fig, app.Traces_fig);
                app.embed_tree(10200);
            elseif strcmpi(mode.Text, 'clusters') 
                app.BranchDropDownLabel.Text = 'cluster #';
                app.BranchDropDown.Items = splitlines(sprintf('\n%i',1:nanmax(app.expe.dimensionality.cluster_idx)));
                app.BranchDropDown.Items{1} = 'All'; 
                app.expe.dimensionality.clust_meth         = app.MethodDropDown_2.Value;
                app.expe.dimensionality.clust_thr_or_n     = app.NClustThrEditField.Value;                
                app.expe.cluster_factors();                
                app.expe.plot_cluster_tree('', app.Loadings_fig, app.Traces_fig); 
                app.embed_tree(gcf);
            end
            app.resume()
        end

        % Value changed function: BranchDropDown
        function BranchDropDownValueChanged(app, event)
            app.pause()
            value = str2num(app.BranchDropDown.Value);
            if strcmpi(app.TreeDisplayButtonGroup.SelectedObject.Text, 'factors') 
                if ~isempty(value)
                    app.expe.plot_dim_tree(value);
                    app.embed_tree(gcf);
                    rescaled_traces = app.expe.rescaled_traces(:, app.expe.dimensionality.all_ROIs(app.expe.dimensionality.valid_trace_idx));
                    value          = app.expe.dimensionality.LoadingsPM(:,value)/sum(app.expe.dimensionality.LoadingsPM(:,value));       
                    delete(app.highlight_trace);
                    app.highlight_trace = plot(app.Traces_fig, app.expe.t, nanmean(rescaled_traces'.* value, 1),'Color','r','LineWidth',2);
                else
                    app.expe.plot_factor_tree('','','', app.Loadings_fig, app.Traces_fig);
                    app.embed_tree(gcf);
                end
            elseif strcmpi(app.TreeDisplayButtonGroup.SelectedObject.Text, 'clusters') 
                if ~isempty(value)
                    cluster_idx = app.expe.dimensionality.cluster_idx;
                    cluster_idx(cluster_idx ~= value) = 0;
                    app.expe.ref.plot_value_tree(cluster_idx, find(app.expe.dimensionality.valid_trace_idx), app.expe.default_handle, 'Clusters','',10200, 'regular', 'viridis');      
                    app.embed_tree(gcf);
                    rescaled_traces = app.expe.rescaled_traces(:, app.expe.dimensionality.valid_trace_idx);
                    value           = nanmean(rescaled_traces(:, cluster_idx>0),2);
                    delete(app.highlight_trace);
                    app.highlight_trace = plot(app.Traces_fig, app.expe.t, value,'Color','r','LineWidth',2);
                else
                    app.expe.plot_cluster_tree('',app.Loadings_fig, app.Traces_fig);
                    app.embed_tree(gcf);
                end
            end
            app.resume();
        end

        % Button pushed function: LoaddataatTimepointButton
        function LoaddataatTimepointButtonPushed(app, event)
            app.pause()
            app.ExploreFactorsUIFigure.HandleVisibility = 'callback';
            t = ginput(1);
            app.ExploreFactorsUIFigure.HandleVisibility = 'off';
            t = t(1);t_starts = app.expe.timescale.t_start_nogap;
            record = find(t_starts < t, 1, 'last');
            t_in_record = t - t_starts(record);
            pt_in_record = round(t_in_record / app.expe.timescale.sr(record));
            
            app.load_bap(record, pt_in_record);
            app.resume();
        end

        % Button pushed function: LoadTrialButton
        function LoadTrialButtonPushed(app, event)
            app.pause()
            app.ExploreFactorsUIFigure.HandleVisibility = 'callback';
            pt = ginput(1);
            app.ExploreFactorsUIFigure.HandleVisibility = 'off';
            t = pt(1);t_starts = app.expe.timescale.t_start_nogap;
            record = find(t_starts < t, 1, 'last');
            t_in_record = t - t_starts(record);
            pt_in_record = round(t_in_record / app.expe.timescale.sr(record));
            source = app.expe.arboreal_scans{record}.data_folder;
            ap = app.expe.arboreal_scans{record}.analysis_params;
            p = analysis_params(   'data_folder',ap.data_folder,...
                                   'data_type'      , 'concatenated',...
                                   'rendering_mode', app.RenderingMode.Value,...
                                   'tracing_source' , 'swc'         , ...
                                   'signal_channel' , 2             ,...
                                   'registration_channel', 2,.... % to fix
                                   'registration'   , true          ,...
                                   'registration_reload','auto_offsets.mat',...
                                   'smoothing'      , [10,0]        ,...
                                   'mask_method'    , ''   ,...
                                   'mask_value'     , ''     ,...
                                   'compression'    , '2D'          ,...
                                   'rendering'      , true         ,...
                                   'ROIs',find(app.expe.dimensionality.valid_trace_idx),...
                                   'z_score'        ,-5);
            data = load_ribbon_scan(p); 
            app.resume()
        end

        % Value changed function: MethodDropDown, NFactorsSpinner
        function MethodDropDownValueChanged(app, event)
            app.pause()
            app.expe.rendering = false;
            app.expe.get_dimensionality(false,app.NFactorsSpinner.Value,app.MetricDropDown.Value,app.MethodDropDown.Value,app.MethodDropDown_2.Value);
            app.expe.rendering = true;
            cla(app.Traces_fig)
            app.expe.plot_factor_tree('','','', app.Loadings_fig, app.Traces_fig);
            app.embed_tree(10200);
            app.resume()
        end

        % Value changed function: MethodDropDown_2, 
        % NClustThrEditField
        function MethodDropDown_2ValueChanged(app, event)
            app.pause()
            app.expe.dimensionality.clust_meth         = app.MethodDropDown_2.Value;
            app.expe.dimensionality.clust_thr_or_n     = app.NClustThrEditField.Value;                
            app.expe.cluster_factors();
            app.expe.plot_cluster_tree('', app.Loadings_fig, app.Traces_fig); 
            app.embed_tree(10200);
            app.resume()
        end

        % Button pushed function: GenerateimagesButton
        function GenerateimagesButtonPushed(app, event)
            app.pause()
            events = [];
            selection = app.Tree.CheckedNodes;
            for el = 1:numel(selection)
                txt = selection(el).Text;
                if ~contains(txt, 'Record')
                    pos = strfind(txt , 't = ');
                    par = selection(el).Parent;
                    par_txt = str2double(par.Text(7:end));
                    if ~isempty(pos)
                        events = [events; [par_txt, str2double(txt(6:pos-1)), str2double(txt(pos+4:end))]];                    
                    end
                end
            end
            
            pt_starts = cumsum([1,app.expe.timescale.tp]);
            im = [];
            for ev = 1:size(events, 1)            
                record = events(ev, 1);
                tp = events(ev, 3);
                start_in_global = pt_starts(record) + 1;
                event_nb = events(ev, 2);
                event_bloc_idx = app.event_index(event_nb,3)
                window = app.expe.event.t_win{event_bloc_idx};
                im = cat(3, im, app.load_bap(record, tp - start_in_global, window- start_in_global));
                start = nanmax(events(ev, 3)-100, 1);
                stop = nanmin(events(ev, 3)+100, numel(app.expe.timescale.global_timescale));
                xlim(app.Traces_fig, app.expe.timescale.global_timescale([start, stop]));
                pause(0.05)
            end
            show_stack_GUI(im)
            app.resume();
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = event.Source.SelectedNodes;
            pos =  strfind(checkedNodes.Text , 't = ');
            if ~isempty(pos)
                tp  =  str2double(checkedNodes.Text(pos+4:end));
                start = nanmax(tp-100, 1);
                stop = nanmin(tp+100, numel(app.expe.timescale.global_timescale));
                xlim(app.Traces_fig, app.expe.timescale.global_timescale([start, stop]));
                app.current_bap = plot(app.Traces_fig, app.expe.timescale.global_timescale([tp,tp]),[nanmin(app.Traces_fig.YLim), nanmax(app.Traces_fig.YLim)],'r--');
            end

        end

        % Button pushed function: SelectEventButton
        function SelectEventButtonPushed(app, event)
            app.ExploreFactorsUIFigure.HandleVisibility = 'callback';
            t = ginput(1);
            [~,t] = nanmin(abs(app.expe.timescale.global_timescale - t(1)));
            app.ExploreFactorsUIFigure.HandleVisibility = 'off';
            all         =  vertcat(app.expe.event.peak_time{:});
            [~, loc]    = nanmin(abs(all-t(1))');
            record      = app.event_index(loc,1);
            event       = app.event_index(loc,2);
            %event_block = app.event_index(18,3);            
            idx = find(contains({app.Tree.Children(record).Children.Text}, ['Event ',num2str(event),' t = ']));
            app.Tree.CheckedNodes =  [app.Tree.CheckedNodes,;app.Tree.Children(record).Children(idx)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExploreFactorsUIFigure = uifigure('Visible', 'off');
            app.ExploreFactorsUIFigure.Name = 'Explore Factors';
            app.Tree_fig = uiaxes(app.ExploreFactorsUIFigure);
            title(app.Tree_fig, 'Title')
            xlabel(app.Tree_fig, 'X')
            ylabel(app.Tree_fig, 'Y')
            zlabel(app.Tree_fig, 'Z')
            app.Traces_fig = uiaxes(app.ExploreFactorsUIFigure);
            title(app.Traces_fig, 'Title')
            xlabel(app.Traces_fig, 'X')
            ylabel(app.Traces_fig, 'Y')
            zlabel(app.Traces_fig, 'Z')
            app.Loadings_fig = uiaxes(app.ExploreFactorsUIFigure);
            title(app.Loadings_fig, 'Title')
            xlabel(app.Loadings_fig, 'X')
            ylabel(app.Loadings_fig, 'Y')
            zlabel(app.Loadings_fig, 'Z')
            app.TreeDisplayButtonGroup = uibuttongroup(app.ExploreFactorsUIFigure);
            app.TreeDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TreeDisplayButtonGroupSelectionChanged, true);
            app.TreeDisplayButtonGroup.Title = 'Tree Display';
            app.BranchesButton = uitogglebutton(app.TreeDisplayButtonGroup);
            app.BranchesButton.Text = 'Branches';
            app.BranchesButton.Value = true;
            app.FactorsButton = uitogglebutton(app.TreeDisplayButtonGroup);
            app.FactorsButton.Text = 'Factors';
            app.ClustersButton = uitogglebutton(app.TreeDisplayButtonGroup);
            app.ClustersButton.Text = 'Clusters';
            app.MethodDropDownLabel = uilabel(app.TreeDisplayButtonGroup);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.TreeDisplayButtonGroup);
            app.MethodDropDown.Items = {'pca', 'factoran', 'nnmf'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'pca';
            app.MethodDropDown_2Label = uilabel(app.TreeDisplayButtonGroup);
            app.MethodDropDown_2Label.Text = 'Method';
            app.MethodDropDown_2 = uidropdown(app.TreeDisplayButtonGroup);
            app.MethodDropDown_2.Items = {'none', 'kmeans', 'hierarchical', 'dbscan'};
            app.MethodDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MethodDropDown_2ValueChanged, true);
            app.MethodDropDown_2.Value = 'none';
            app.NFactorsSpinnerLabel = uilabel(app.TreeDisplayButtonGroup);
            app.NFactorsSpinnerLabel.Text = 'N Factors';
            app.NFactorsSpinner = uispinner(app.TreeDisplayButtonGroup);
            app.NFactorsSpinner.Limits = [0 20];
            app.NFactorsSpinner.RoundFractionalValues = 'on';
            app.NFactorsSpinner.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.NFactorsSpinner.Value = 6;
            app.NClustThrEditFieldLabel = uilabel(app.TreeDisplayButtonGroup);
            app.NClustThrEditFieldLabel.Text = 'N Clust/Thr';
            app.NClustThrEditField = uieditfield(app.TreeDisplayButtonGroup, 'numeric');
            app.NClustThrEditField.Limits = [0 Inf];
            app.NClustThrEditField.ValueChangedFcn = createCallbackFcn(app, @MethodDropDown_2ValueChanged, true);
            app.MetricDropDownLabel = uilabel(app.TreeDisplayButtonGroup);
            app.MetricDropDownLabel.Text = 'Metric';
            app.MetricDropDown = uidropdown(app.TreeDisplayButtonGroup);
            app.MetricDropDown.Items = {'full', 'peaks', 'whisker', 'encoder', 'wheel'};
            app.MetricDropDown.Value = 'peaks';
            app.BranchDropDownLabel = uilabel(app.ExploreFactorsUIFigure);
            app.BranchDropDownLabel.Text = 'Branch';
            app.BranchDropDown = uidropdown(app.ExploreFactorsUIFigure);
            app.BranchDropDown.Items = {'All'};
            app.BranchDropDown.ValueChangedFcn = createCallbackFcn(app, @BranchDropDownValueChanged, true);
            app.BranchDropDown.Value = 'All';
            app.LoaddataatTimepointButton = uibutton(app.ExploreFactorsUIFigure, 'push');
            app.LoaddataatTimepointButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataatTimepointButtonPushed, true);
            app.LoaddataatTimepointButton.Text = 'Load data at Timepoint';
            app.LoadTrialButton = uibutton(app.ExploreFactorsUIFigure, 'push');
            app.LoadTrialButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrialButtonPushed, true);
            app.LoadTrialButton.Text = 'Load Trial';
            app.DropDownLabel = uilabel(app.ExploreFactorsUIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.RenderingMode = uidropdown(app.ExploreFactorsUIFigure);
            app.RenderingMode.Items = {'mosaic', 'realistic'};
            app.RenderingMode.Value = 'mosaic';
            app.Tree = uitree(app.ExploreFactorsUIFigure, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.GenerateimagesButton = uibutton(app.ExploreFactorsUIFigure, 'push');
            app.GenerateimagesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateimagesButtonPushed, true);
            app.GenerateimagesButton.Text = 'Generate images';
            app.SelectEventButton = uibutton(app.ExploreFactorsUIFigure, 'push');
            app.SelectEventButton.ButtonPushedFcn = createCallbackFcn(app, @SelectEventButtonPushed, true);
            app.SelectEventButton.Text = 'Select Event';
            app.ShowexcludedROIsCheckBox = uicheckbox(app.ExploreFactorsUIFigure);
            app.ShowexcludedROIsCheckBox.Text = 'Show excluded ROIs';
            % Show the figure after all components are created
            app.ExploreFactorsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = explore_factors(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExploreFactorsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExploreFactorsUIFigure)
        end
    end
end